classdef DSP_PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TextArea              matlab.ui.control.TextArea
        TextAreaLabel         matlab.ui.control.Label
        OUTPUTEditField       matlab.ui.control.EditField
        OUTPUTEditFieldLabel  matlab.ui.control.Label
        READTXTFILEButton     matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: OUTPUTEditField, READTXTFILEButton
        function READTXTFILEButtonPushed(app, event)

%READ TEXT FILE KNOP
%KIES EEN FILE IN DATA/PP01 FOLDER
%TOON FILE TITEL IN EDIT FIELD
%TOON FILE CONTENT IN TEXTAREA

[filename,path]=uigetfile("DATA/PP01/*.txt");
app.OUTPUTEditField.Value = filename;
pathToFile = path + "/" + filename;
fileID = fopen(pathToFile);
C = textscan(fileID, '%s', 'Delimiter', '\n');
A = readmatrix(pathToFile,delimitedTextImportOptions('DataLines',[4,Inf]));
app.TextArea.Value = A;
fclose(fileID);
figure(app.UIFigure);


        end

        % Value changing function: OUTPUTEditField
        function OUTPUTEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
%             value = app.TextArea.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.READTXTFILEButton = uibutton(app.UIFigure, 'push');
            app.READTXTFILEButton.ButtonPushedFcn = createCallbackFcn(app, @READTXTFILEButtonPushed, true);
            app.READTXTFILEButton.Text = 'READ TXT FILE';
            app.OUTPUTEditFieldLabel = uilabel(app.UIFigure);
            app.OUTPUTEditFieldLabel.Text = 'OUTPUT';
            app.OUTPUTEditField = uieditfield(app.UIFigure, 'text');
            app.OUTPUTEditField.ValueChangedFcn = createCallbackFcn(app, @READTXTFILEButtonPushed, true);
            app.OUTPUTEditField.ValueChangingFcn = createCallbackFcn(app, @OUTPUTEditFieldValueChanging, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end